classdef Cobblefinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        TabGroup2                   matlab.ui.container.TabGroup
        YagiPlotTab                 matlab.ui.container.Tab
        GridLayout12                matlab.ui.container.GridLayout
        YagiAxes                    matlab.ui.control.UIAxes
        HistogramTab                matlab.ui.container.Tab
        GridLayout11                matlab.ui.container.GridLayout
        RSSIAxes                    matlab.ui.control.UIAxes
        GridLayout10                matlab.ui.container.GridLayout
        SpectrumAxes                matlab.ui.control.UIAxes
        GPSAxes                     matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        ControlsTab                 matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout13                matlab.ui.container.GridLayout
        TagSlider                   matlab.ui.control.Slider
        TagSliderLabel              matlab.ui.control.Label
        PausegraphsButton           matlab.ui.control.StateButton
        GridLayout8                 matlab.ui.container.GridLayout
        GPSCOMLabel_2               matlab.ui.control.Label
        GPSCOMLamp_2                matlab.ui.control.Lamp
        AntennaLabel                matlab.ui.control.Label
        AntennaCOMLamp_2            matlab.ui.control.Lamp
        RunningLabel                matlab.ui.control.Label
        RunningLamp                 matlab.ui.control.Lamp
        TagIDLabel                  matlab.ui.control.Label
        PrevioustagButton           matlab.ui.control.Button
        NexttagButton               matlab.ui.control.Button
        ConfigureTab                matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        GPSBypassLabel              matlab.ui.control.Label
        GPSBypassSwitch             matlab.ui.control.Switch
        GridLayout5                 matlab.ui.container.GridLayout
        LoadprevPositionsButton     matlab.ui.control.Button
        RSSIdotsCheckBox            matlab.ui.control.CheckBox
        ZoomRadiusmDropDownLabel    matlab.ui.control.Label
        ZoomRadiusmDropDown         matlab.ui.control.DropDown
        SnailtrailsCheckBox         matlab.ui.control.CheckBox
        MoplinesCheckBox            matlab.ui.control.CheckBox
        UISwitchLabel               matlab.ui.control.Label
        UILamp                      matlab.ui.control.Lamp
        GPSCOMListBox               matlab.ui.control.ListBox
        GPSCOMSwitch                matlab.ui.control.Switch
        GPSCOMSwitchLabel           matlab.ui.control.Label
        GPSCOMLamp                  matlab.ui.control.Lamp
        AntennaCOMListBox           matlab.ui.control.ListBox
        AntennaCOMSwitch            matlab.ui.control.Switch
        AntennaCOMSwitchLabel       matlab.ui.control.Label
        AntennaCOMLamp              matlab.ui.control.Lamp
        ConsoleTab                  matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        ConsoleOutputTextArea       matlab.ui.control.TextArea
        ConsoleOutputTextAreaLabel  matlab.ui.control.Label
        GridLayout7                 matlab.ui.container.GridLayout
        REMOVEcobbleButton          matlab.ui.control.Button
        LogCobbleButton             matlab.ui.control.Button
        RefreshButton               matlab.ui.control.Button
        UImssg                      matlab.ui.control.Label
    end

    
    properties (Access = private)      
        % General variables
        DSP % stuct containing boolean vars to enable display  of GUI features
        datalog % struct containing vars to save the data
        lajollagrey %Perceptually-accurate colormap (Crameri)

        % Timers (and their associated variables)
        % __________
        antCOMtmr % Antenna data aquisition

        antenna % Serial obj
        antCOM % COM#
        gps % serial obj
        gpsCOM % COM
        taglist % hex list of aRFID tags to listen for
        numtags % length(taglist)
        tagflags % length(taglist) - boolean values for flagged tags
        tagflags2 % length(taglist) - used to remember natural tagged flags vs loaded from a file
        RSSI % Signal strength

        gpsspoof % a placeholder $GPGGA string
        gpsspoofbool = false; % boolean to enable gpsspoof
        gpsspooftmr % fixed period UI update without GPS
        
        % __________
        uitmr

        GPScord % GPS position
        GPStime % GPS UTC time
        MOPlat % MOP Latitudes seperated by NaN values
        MOPlon % MOP longitudes seperated by NaN values
        snail % Snail trail data
        PLOTS % GPS plot struct
        mapoffset % Zoom scale (in degrees)
        RSSIscat % Scatterplot signal strength data
        logpos % Logged cobble position data for tagflags
        prevload % TABLE of Previous cobble log information
        prevfile %path to loaded file
        prevpos % Previous cobble position data
        YagiRSSI % plot antenna RSSI over time
        tagfocus % highlight RSSI and show scatterplot of this tag index

        % __________
        % minitmrs (singleshot)
        refreshtmr % a singleShot timer to run UIupdate 1 time
        UIdispmssg % display a message to the user
        debug % developer mode
    end
    
    methods (Access = private)     
        %% UI Update
        function UIupdate(app,src,~,~) %(app, obj, event, string_arg)
            % callback function for uitmr to update screen elements
%             tic %stats

            delete(findobj(app.GPSAxes, 'type', 'text')); %clear text labels on screen

            % _____ RSSI bar graph _____
            set(app.PLOTS.RSSI,'XData',[1:1:app.numtags],'YData',app.RSSI); % set bar graph to RSSI data
            app.PLOTS.RSSI.YData(app.tagflags) = repmat(160,sum(app.tagflags,'all'),1); % change all tagged cobbles to RSSI=160
            app.PLOTS.RSSI.CData(:,:) = repmat([0.2, 0.5, 0.7410].*1.2,app.numtags,1); %test set all to blue
            app.PLOTS.RSSI.CData(app.tagflags,:) = repmat([0.4660, 0.6740, 0.1880],sum(app.tagflags,'all'),1); % set flagged tags green
            app.PLOTS.RSSI.CData(app.tagfocus,:) = [0.8500, 0.3250, 0.0980]; % highlight tagfocus in orange
            
            % _____ GPS Plot _____
            % 'snail' - display snail trails (previous GPS line)
            if app.DSP.snail
                app.snail=[app.GPScord;app.snail(1:end-1,:)];
                set(app.PLOTS.snail,'XData',app.snail(:,2),'YData',app.snail(:,1))
            else
                set(app.PLOTS.snail,'XData',[],'YData',[])
            end

            % Log RSSI 
            if ~app.datalog.refresh % do not run on manual refresh
                comparerssi=app.RSSI>app.RSSIscat(:,3);
                if any(comparerssi) % if new max(RSSI)
                    rssitemp=app.GPScord.*comparerssi;
                    app.RSSIscat(comparerssi,:)=[rssitemp(comparerssi,1),rssitemp(comparerssi,2),app.RSSI(comparerssi)];
                    clear rssitemp
                end
                if app.DSP.RSSIdots % display signal strength at GPS coordinate
                    set(app.PLOTS.RSSIdot,'Xdata',app.RSSIscat(:,2),'Ydata',app.RSSIscat(:,1),'SizeData',app.RSSIscat(:,3));
                end
            end

            % Set map limits
            lat=[app.GPScord(1)-app.mapoffset,app.GPScord(1)+app.mapoffset];
            lon=[app.GPScord(2)-app.mapoffset,app.GPScord(2)+app.mapoffset];
            ylim(app.GPSAxes,lat)
            xlim(app.GPSAxes,lon)

            % Plot GPS cord
            set(app.PLOTS.Xmarker,'XData',app.GPScord(2),'YData',app.GPScord(1))
            set(app.PLOTS.Omarker,'XData',app.GPScord(2),'YData',app.GPScord(1))

            % Display CURRENT logged cobble flags
            set(app.PLOTS.Flagpos,'XData',app.logpos(app.tagflags,2),'YData',app.logpos(app.tagflags,1),...
                'Sizedata',50,'CData',[0 1 0],'Marker','p')
            for itags=1:app.numtags
                tstring=sprintf('Cobble #%d',itags);
                text(app.GPSAxes,app.logpos(itags,2)+0.0001,app.logpos(itags,1),tstring,'Color','green')
            end

            % Display PREVIOUS logged cobble flags
            set(app.PLOTS.Prevpos,'XData',app.prevpos(:,2),'YData',app.prevpos(:,1),...
                'Sizedata',50,'CData',[0.8500 0.3250 0.0980],'Marker','p')
            for itags=1:app.numtags
                tstring=sprintf('Cobble #%d',itags);
                text(app.GPSAxes,app.prevpos(itags,2)+0.0001,app.prevpos(itags,1),tstring,'Color',[0.8500 0.3250 0.0980])
            end

            % Enable GPS lamp
            taskEx = get(src,'TasksExecuted');
            if mod(taskEx,2)
                app.UILamp.Enable='on';
                app.UILamp.Color='g';
            else
                app.UILamp.Enable='off';
            end
            % Transfer lamp status to main page
            app.AntennaCOMLamp_2.Color=app.AntennaCOMLamp.Color;
            app.GPSCOMLamp_2.Color=app.GPSCOMLamp.Color;
            
            % _____ Yagi Antenna plot _____
            if ~app.DSP.yagigraph
                app.YagiRSSI=[app.RSSI';app.YagiRSSI(1:end-1,:)]; % set brief RSSI history
            end
            app.RSSI(:)=0; %clear RSSI once plotted
            yagipltfocus=app.YagiRSSI(:,app.tagfocus); 
            yagipltfocusavg=yagipltfocus;
            yagipltfocusavg(yagipltfocusavg==0)=nan;
            yagipltfocusavg=movmean(yagipltfocusavg,3,'omitnan');
        
            set(app.PLOTS.antenna,'XData',[1:size(app.YagiRSSI,1)],'YData',yagipltfocus,'Color',[0 0.4470 0.7410]); % set plot to RSSI data
            set(app.PLOTS.antennaavg,'XData',[1:size(app.YagiRSSI,1)],'YData',yagipltfocusavg,'LineWidth',3,'Color',[0.8500 0.3250 0.0980]); % moving average

            if ~all(isnan(yagipltfocusavg)) %as long as yagipltfocusavg is not only NaN values (empty)
                off=0.3;
                meanie=mean(yagipltfocusavg,'omitnan');
                ylim(app.YagiAxes,[meanie*(1-off), meanie*(1+off)])
            end

            % _____ Spectrum Antenna plot _____
            set(app.PLOTS.spectrum,'CData',[app.YagiRSSI,zeros(size(app.YagiRSSI,1),1)]'); %pcolor paints from bottom left corner, adding a row of fill values corrects the display to show all tags
            app.PLOTS.spectrum.CData(app.tagflags,:) = ones(sum(app.tagflags,'all'),size(app.YagiRSSI,1)); %change all tagged cobbles to RSSI=1 (black)

            % Running lamp status
            if ~app.datalog.refresh % user did NOT Refresh
                app.RunningLamp.Color='g';
                % alternate lamp state
                if mod(app.GPStime(15),2) %check if UTC seconds are even or odd
                    app.RunningLamp.Enable='on';
                    app.RunningLamp.Color='g';
                else
                    app.RunningLamp.Enable='off';
                end
                app.RunningLabel.Text='logging!';
            else % user DID refresh
                app.datalog.refresh=false;
                app.RunningLamp.Color='r';
                app.RunningLamp.Enable='off';
                app.RunningLabel.Text='NOT logging';
            end

            drawnow limitrate; %limitrate critical (improves stability)
            pause(0.001); % the pause allows this loop to be interrupted

            % ___ stats ___
%             time=toc;
%             fprintf('uitmr time to run %.2f\n',time)
        end


        %% lat2meters
        function degrees=meters2GPS(~,Lat,meters)
            lat_m=111e3;
            m2gps=lat_m*cosd(Lat);

            degrees=meters/m2gps;            
        end

        %% Antenna Data Aquisition
        function readant(app,src,~,~)
            a=readline(src);
            a=a{1}(2:end);
            
            % Interpret Serial & output data
            RSSI_OFFSET=255;

            [A,num_elements] = sscanf(a,'%2x%6x01',2); % parse hex to RSI & tag ID
            index = A(2) - app.taglist(1) + 1; %matlab arrays are one based,not zero based
            if num_elements==2 && index <= app.numtags && index > 0
                app.RSSI(index)=RSSI_OFFSET-A(1);
            end
        end


        %% GPS Data Aquisition
        function readgps(app,src,~,~)
%             tic;
            if app.gpsspoofbool
                scandata=app.gpsspoof;
            else
                scandata=readline(src);
            end

            % Interpret GPS information
            gpssersplit=strsplit(scandata,','); % seperate by comma

            if length(gpssersplit)==14 
                if gpssersplit{1}=="$GPGGA"
                    % Date & time
                    app.GPStime=[datestr(now,'yyyymmdd'),'T',gpssersplit{2},'Z'];
                
                    % GPS Lat/Lon
                    if gpssersplit{4}=="N"
                        app.GPScord(1)=str2double(gpssersplit{3}(1:2)) + str2double(gpssersplit{3}(3:13))/60;
                    elseif gpssersplit{4}=="S"
                        app.GPScord(1)=-str2double(gpssersplit{3}(1:2)) + str2double(gpssersplit{3}(3:13))/60;
                    else
                        app.GPScord(1)=nan;
                        telluser(app,"GPS line read error",1)
                    end
                    if gpssersplit{6}=="W"
                        app.GPScord(2)=-str2double(gpssersplit{5}(1:3)) - str2double(gpssersplit{5}(4:14))/60;
                    elseif gpssersplit{6}=="E"
                        app.GPScord(2)=str2double(gpssersplit{5}(1:3)) + str2double(gpssersplit{5}(4:14))/60;
                    else
                        app.GPScord(2)=nan;
                        telluser(app,"GPS line read error",1)
                    end
                end
            else
                telluser(app,"GPS line read error",1)
                errorMessage = sprintf('Unable to read GPS serial COM.  Wait until GPS has aquired satellites or finishes booting.');
                uiwait(warndlg(errorMessage));
                disp(src) %debug - display GPS serial line
                app.snail=[[nan,nan];app.snail(1:end-1,:)]; % prevent any "snail jumps
%                 app.GPScord(:)=nan; % "hold" last known GPS position by commenting this line
            end

            wait(app.uitmr) % wait to run until UI is ready for an update
            start(app.uitmr) % run UIupdate once GPS fix aquired

            % debug stats
%             time=toc;
%             fprintf('GPS time to run %.2f\n',time)
        end


        %% Update taglabel
        function updatetaglabel(app)
            text = sprintf('Tag ID: %d\n(Tag # %d)',app.taglist(app.tagfocus),app.tagfocus);
            app.TagIDLabel.Text=text;
        end


        %% Set Refresh State
         function setrefreshstate(app,src,~,~)
            app.datalog.refresh=true;
         end


         %% Console mssg log
         function consolemssg(app,src,~,~)
            app.ConsoleOutputTextArea.Value=[app.ConsoleOutputTextArea.Value; datestr(datetime('now'),30), '>> ', app.UImssg.UserData];
            app.UImssg.Visible=true;
         end


         %% UI mssg fade
         function UImssgfade(app,src,~,~)
            app.UImssg.Visible=false;
            app.UImssg.Text='';
            app.UImssg.UserData='';
         end

         %% Tell user fcn
         function telluser(app,mssg,dsp)
             % show user a UImssg (dsp=true) or log to console (dsp=false)
            arguments
                app
                mssg {mustBeText} % message to be displayed
                dsp (1,1) logical % T/F to show message in display window or only in console
            end
            
            if isequal(get(app.UIdispmssg,'Running'),'on')
                stop(app.UIdispmssg);
            end
            if dsp
                app.UImssg.Text=mssg;
                app.UImssg.UserData=mssg;
            else
                app.UImssg.UserData=mssg;
            end
            start(app.UIdispmssg);
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Layout parameters
            app.debug=true; %enter debug mode (CTRL+F "if app.debug")
            if app.debug
                app.UIFigure.WindowState='normal';
                dbstop if error
            else
                app.UIFigure.WindowState='maximized';

                % Disable developer options
                app.LoadprevPositionsButton.Visible='off';
                app.GPSBypassLabel.Visible='off';
                app.GPSBypassSwitch.Visible='off';
            end
            app.UIFigure.Units="normalized";

            if isdeployed
                disp('I am a deployed app')
            else
                addpath(genpath('C:\Users\ccblack\Documents\Carson\Projects\IOD_activeRFID')) %FLAG ensure required files are passed with the compiler
% turn off when exporting
            end

            %% Startup Code       
            % Import Scientific color map
            importscicolormap='lajollagrey.mat';
            if ~exist(importscicolormap, 'file')
              message = sprintf('%s does not exist', importscicolormap);
              uiwait(warndlg(message));
            else
              t = load(importscicolormap);
              app.lajollagrey=flipud(t.LaJollaCustom);
            end
            clear importscicolormap t message
            %{
The Scientific colour map lajolla (Crameri 2018) is used in
this study to prevent visual distortion of the data and exclusion of
readers with colour-vision deficiencies (Crameri et al., 2020).
            %}


            app.DSP=struct('snail',app.SnailtrailsCheckBox.Value,...
                'mop',app.MoplinesCheckBox.Value,...
                'RSSIdots',app.RSSIdotsCheckBox.Value,...
                'yagigraph',app.PausegraphsButton.Value);

            % Fix GUI elements
            app.AntennaCOMListBox.Items=cellstr(serialportlist('all'));
            app.AntennaCOMListBox.Value={}; %flag - replace with default COM value

            app.GPSCOMListBox.Items=cellstr(serialportlist('all'));
            app.GPSCOMListBox.Value={}; %flag - replace with default COM value

            % RSSI
            % Import tag list
            [fid, msg]=fopen('taglist.txt','r');
            if fid < 0
                error('Failed to open file "%s" because: "%s"', 'taglist.txt', msg);
            end
            C = textscan(fid,'%s');
            app.taglist = hex2dec(C{1}); % aquire tag IDs
            fclose(fid);
            app.numtags=length(app.taglist); % # of tags tracking
            app.RSSI = zeros(app.numtags,1); % Initialize RSSI to zeros
            app.tagflags=logical(~ones(app.numtags,1)); % Initialize tagflags to FALSE
            app.tagflags2=app.tagflags; %initialize tagflags2 to false
            app.YagiRSSI=zeros(45,app.numtags);
            
            % Fix Tag GUI elements
            app.tagfocus=app.TagSlider.Value;
            text = sprintf('Tag ID: %d\n(Tag # %d)',app.taglist(app.tagfocus),app.tagfocus);
            app.TagIDLabel.Text=text;
            app.TagSlider.Limits=[1 app.numtags];

            %  KMl
            [app.MOPlon,app.MOPlat]=MOPelementreducer('MOPs_SD_County.kml'); % Load MOP lines
            app.GPScord=[32.866354602512274, -117.25400047613265]; %[Lat, Lon] %test -Fix static GPS cord
            app.mapoffset= meters2GPS(app,app.GPScord(1),str2double(app.ZoomRadiusmDropDown.Value));
            app.snail=nan(14400,2);
            app.snail=[app.GPScord;app.snail(1:end-1,:)];
            app.snail=[[nan,nan];app.snail(1:end-1,:)]; % prevent any "snail jumps"


            %% _____ Initialize graphs _____
            app.PLOTS=struct('Xmarker',{scatter(app.GPSAxes,[],[])},...
                'Omarker',{scatter(app.GPSAxes,[],[])},...
                'MOP',{plot(app.GPSAxes,[],[])},...
                'snail',{plot(app.GPSAxes,[],[])},...
                'RSSIdot',{scatter(app.GPSAxes,[],[])},...
                'Flagpos',{scatter(app.GPSAxes,[],[])},...
                'Prevpos',{scatter(app.GPSAxes,[],[])},... %current
                'RSSI',{bar(app.RSSIAxes,[])},...
                'antenna',{plot(app.YagiAxes,[])},...
                'antennaavg',{plot(app.YagiAxes,[])},...
                'spectrum',{pcolor(app.SpectrumAxes,[])},...
                'spectrum_colorbar',{colorbar(app.SpectrumAxes,"Limits",[0 160])});

            % ___ RSSI histogram bar graph ___
            hold(app.RSSIAxes,"on")
            app.PLOTS.RSSI=bar(app.RSSIAxes,app.taglist,app.RSSI);
            app.PLOTS.RSSI.FaceColor = 'flat';
            ylim(app.RSSIAxes,[0 160])

            % ___ GPS plot ___
            app.PLOTS.Xmarker= scatter(app.GPSAxes,app.GPScord(2),app.GPScord(1),200,'blue','x','LineWidth',1); % Position marker
            hold(app.GPSAxes,'on')
            app.PLOTS.Omarker= scatter(app.GPSAxes,app.GPScord(2),app.GPScord(1),200,'blue','o','LineWidth',1); % Position marker
            % 'mop' - display mop lines
            if app.DSP.mop
                app.PLOTS.MOP=plot(app.GPSAxes,app.MOPlon,app.MOPlat,'red');
            end
            % 'snail' - display snail trails (previous GPS line)
            if app.DSP.snail
                app.snail(end+1,:)=app.GPScord;
                % make a green line that follows user GPS position
                app.PLOTS.snail=plot(app.GPSAxes,app.snail(:,2),app.snail(:,1),'green');
            end
            % 'RSSI dots' - signal strength indicators tied to GPS pos.
            app.RSSIscat=ones(app.numtags,3);
            app.RSSIscat(:,1:2)=repmat(app.GPScord,length(app.RSSIscat),1);
            % put an orange circle where the RSSI is high
            app.PLOTS.RSSIdot= scatter(app.GPSAxes,app.RSSIscat(:,2),app.RSSIscat(:,1),app.RSSIscat(:,3),[0.8500, 0.3250, 0.0980],'o','LineWidth',0.5);

           % 'Flagpos' - Display location of logged cobbles
           app.logpos=repmat([999, 999],app.numtags,1); %default to transient value
           % put a green star where a cobble is logged
           app.PLOTS.Flagpos=scatter(app.GPSAxes,app.logpos(app.tagflags,2),app.logpos(app.tagflags,1),repmat(36,length(app.tagflags(app.tagflags==1))),[0.8500 0.3250 0.0980],'p');

           % 'Prevpos' - Display location of previously tagged cobbles
           app.prevpos=repmat([999, 999],app.numtags,1); %default to transient value
           % put a green star where a cobble is logged
           app.PLOTS.Prevpos=scatter(app.GPSAxes,app.prevpos(:,2),app.prevpos(:,1),repmat(36,length(app.prevpos)),[0.8500 0.3250 0.0980],'p'); %current

            % ___ Yagi RSSI vs time graph ___
            app.PLOTS.antenna=plot(app.YagiAxes,app.YagiRSSI,'Marker','o','LineStyle','none');
            hold(app.YagiAxes,"on")
            app.PLOTS.antennaavg=plot(app.YagiAxes,app.YagiRSSI);
            xlim(app.YagiAxes,[0 45])

            % ___ Spectrum RSSI vs time graph ___
            app.PLOTS.spectrum=pcolor(app.SpectrumAxes,[app.YagiRSSI,zeros(size(app.YagiRSSI,1),1)]');
            app.SpectrumAxes.Colormap= app.lajollagrey;
            app.SpectrumAxes.CLim= [0,160];
            app.PLOTS.spectrum_colorbar.Ticks=[0,20,40,60,80,100,120,140,160];
            app.PLOTS.spectrum_colorbar.TickLabels={'0','20','40','60','80','100','120','140','160'}; %FLAG colorbar is NOT FIXED despite limits and ticks being set, colors will change
            app.PLOTS.spectrum_colorbar.Label.String='Signal Strength';


            %% Datalog startup
            foldername='ActiveCobbleData';
            filename=['aRFIDcobbleLog_',datestr(datetime('now'),'yyyymmdd')];
            app.datalog=struct('filename',filename,...
                'refresh',false,...
                'foldername',foldername,...
                'filepath',fullfile(foldername,filename));
            app.prevfile=dir(fullfile(foldername,filename)); %initialize prevfile to be a folder system struct
            clear foldername filename
            if ~exist(fullfile(app.datalog.foldername), 'dir') % make data folder if it doesn't exist
                mkdir(fullfile(app.datalog.foldername));
            end

            % log startup & header information
            nowutc=[datestr(datetime('now', 'TimeZone','Z'),30),'Z']; % get UTC time
            % write datalog header
            datalogmssg={'UTCtime','TagID','Lat','Lon','notes'};
            writecell(datalogmssg,app.datalog.filepath,'Delimiter','tab','WriteMode','append');
            % write startup time
            startup=sprintf('STARTUP@%s', nowutc);
            datalogmssg={startup,nan,nan,nan,nan};
            writecell(datalogmssg,app.datalog.filepath,'Delimiter','tab','WriteMode','append');

            %% Startup timers (DO THIS LAST)
            pause(1) % ensure proper loading
            
            % refresh timer (singleshot)
            app.refreshtmr=timer('Name','refreshtmr',...
                'ExecutionMode','singleShot',...
                'StartFcn',{@app.setrefreshstate,app},...
                'TimerFcn',{@app.UIupdate,app});

            % console message timer
            app.UIdispmssg=timer('Name','uidispmssg',...
                'ExecutionMode','singleShot',...
                'StartFcn',{@app.consolemssg,app},...
                'StartDelay',5,...
                'TimerFcn',{@app.UImssgfade,app});
            
            %UI timer
            app.uitmr=timer('Name','uitmr',...
                'ExecutionMode', 'singleShot',...
                'TimerFcn', {@app.UIupdate,app},...
                'StartFcn',"disp('start UI timer')",...
                'StopFcn',"disp('stop UI timer')",...
                'ErrorFcn',"disp('UI timer erorr!')",...
                'BusyMode','drop'); %debug busymode -'queue'

            % GPS-bypass timer
            app.gpsspoof= "$GPGGA,220055.00,3252.02728572,N,11715.11190040,W,1,20,0.7,51.133,M,-35.060,M,,*65";
            app.gpsspooftmr=timer('Name','gpsspooftmr',...
                'ExecutionMode', 'fixedRate',...
                'Period', 1,...
                'TimerFcn', {@app.readgps,app},...
                'StartFcn',"disp('start gpsspoof timer')",...
                'StopFcn',"disp('stop gpsspoof timer')",...
                'ErrorFcn',"disp('gpsspoof timer erorr!')",...
                'BusyMode','drop'); %debug busymode -'drop'

            start(app.refreshtmr) % refresh screen once program loaded


            %% Debug startup
            %flag - Only allowed user actions are permitted below this
            %line, variable definitions should be assigned before timer
            %functions start.

            if app.debug
                % Automatically connect COM port demo
                antennacom="COM4";
                if ismember(antennacom,serialportlist('available'))
                    app.AntennaCOMListBox.Value=antennacom;
                    app.AntennaCOMSwitch.Value="On";
                    AntennaCOMListBoxValueChanged(app);
                    AntennaCOMSwitchValueChanged(app);
                end

                % set Zoom
                app.ZoomRadiusmDropDown.Value="1000";
                ZoomRadiusmDropDownValueChanged(app);
            end


            % do not put anything in startupFcn below this line 
            % (account for all variables before starting timers)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            activetimers=timerfindall;
            if isempty(activetimers)
                % no timers exist
            else
                stop(activetimers) % stop all timers
                delete(activetimers) % delete all timers
                temptmr001=timerfindall;
                fprintf('Shutdown all timers\n');
                fprintf('active timers: %s\n',temptmr001);
                clear temptmr001
            end
            
            nowutc=[datestr(datetime('now', 'TimeZone','Z'),30),'Z'];
            shutdown=sprintf('SHUTDOWN@%s', nowutc);
            datalogmssg={shutdown,nan,nan,nan,nan};
            writecell(datalogmssg,app.datalog.filepath,'Delimiter','tab','WriteMode','append');   
            
            try
                delete(app.antenna) % attempt to delete
                delete(app.gps) % attempt to delete
                delete(app)
            catch ME
                errorMessage = sprintf('Error in function %s() at line %d.\n\nError Message:\n%s', ...
			    ME.stack(1).name, ME.stack(1).line, ME.message);
		        uiwait(warndlg(errorMessage));
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            wait(app.uitmr) % wait to run until UI is ready for an update
            wait(app.refreshtmr) % wait for other refreshes from user spam
            start(app.refreshtmr) % refresh screen

            telluser(app,'Refreshed UI',true)

            COMrefreshmssg=false; %default to no display
            antCOMvalue = app.AntennaCOMSwitch.Value;
            GPSCOMvalue = app.GPSCOMSwitch.Value;
            switch antCOMvalue
                case 'On'

                case 'Off'
                    % Refresh list of available COM ports
                    app.AntennaCOMListBox.Items=cellstr(serialportlist('all'));
                    app.AntennaCOMListBox.Value={}; %flag - replace with default COM value
                    telluser(app,'Reset Antenna COM ports',false)
                    COMrefreshmssg=true;
%             end
            switch GPSCOMvalue
                case 'On'

                case 'Off'
                     % Refresh list of available COM ports
                    app.GPSCOMListBox.Items=cellstr(serialportlist('all'));
                    app.GPSCOMListBox.Value={}; %flag - replace with default COM value
                    telluser(app,'Reset GPS COM ports',false)
                    COMrefreshmssg=true;
            end
                if COMrefreshmssg
                    telluser(app,'Refreshed closed COM ports',true)
                end
            end
        end

        % Value changed function: AntennaCOMListBox
        function AntennaCOMListBoxValueChanged(app, event)
            app.antCOM = app.AntennaCOMListBox.Value;
            app.AntennaCOMSwitch.Enable='on';
        end

        % Value changed function: AntennaCOMSwitch
        function AntennaCOMSwitchValueChanged(app, event)
            value = app.AntennaCOMSwitch.Value;
            switch value
                case 'On'
                    % Configure Serial
                    if any(strcmp(serialportlist('available'),app.antCOM))
                        app.antenna=serialport(app.antCOM,9600);
                        configureTerminator(app.antenna,93); % 93 ascii = ']'
                        configureCallback(app.antenna,"terminator",@app.readant);
                    else
                        error('Antenna COM port not available (%s)',app.antCOM);
                    end
                    
                    % Enable antenna indicator lamps
                    app.AntennaCOMLamp.Enable='on';
                    app.AntennaCOMLamp.Color='g';
                    app.AntennaCOMListBox.Enable='off';
                    % Transfer lamp status to main page
                    app.AntennaCOMLamp_2.Color=app.AntennaCOMLamp.Color;
                case 'Off'
                    delete(app.antenna); % disconnect from COM port
                    
                    % Disable antenna indicator lamps
                    app.AntennaCOMLamp.Enable='off';
                    app.AntennaCOMLamp.Color='r';
                    app.AntennaCOMListBox.Enable='on';
                    % Transfer lamp status to main page
                    app.AntennaCOMLamp_2.Color=app.AntennaCOMLamp.Color;
            end
        end

        % Value changed function: GPSCOMListBox
        function GPSCOMListBoxValueChanged(app, event)
            app.gpsCOM = app.GPSCOMListBox.Value;
            app.GPSCOMSwitch.Enable='on';
        end

        % Value changed function: GPSCOMSwitch
        function GPSCOMSwitchValueChanged(app, event)
            value = app.GPSCOMSwitch.Value;
            switch value
                case 'On'
                    % Configure Serial
                    if any(strcmp(serialportlist('available'),app.gpsCOM))
                        app.gps=serialport(app.gpsCOM,115200);
                        configureTerminator(app.gps,"CR/LF");
                        configureCallback(app.gps,"terminator",@app.readgps);
                    else
                        error('GPS COM port not available (%s)',app.gpsCOM);
                    end
                    
                    % Turn on GPS lamp
                    app.GPSCOMLamp.Enable='on';
                    app.GPSCOMLamp.Color='g';
                    app.GPSCOMListBox.Enable='off';

                    % Turn on UI lamp
                    app.UILamp.Color='g';
                    app.GPSCOMLamp.Enable='on';

                    % Turn on Main Running lamp
                    app.RunningLamp.Color='g';
                    app.RunningLamp.Enable='on';

                    % Transfer lamp status to main page
                    app.GPSCOMLamp_2.Color=app.GPSCOMLamp.Color;

                    %Disable loading Cobble positions while running
                    app.LoadprevPositionsButton.Enable='off';
                case 'Off'
                    delete(app.gps); % disconnect COM port
                    
                    app.GPSCOMLamp.Enable='off';
                    app.GPSCOMLamp.Color='r';
                    app.GPSCOMListBox.Enable='on';

                    app.UILamp.Color='r';
                    app.GPSCOMLamp.Enable='off';

                    app.RunningLamp.Color='r';
                    app.RunningLamp.Enable='off';

                    % Transfer lamp status to main page
                    app.GPSCOMLamp_2.Color=app.GPSCOMLamp.Color;

                    %Enable loading Cobble positions while running
                    app.LoadprevPositionsButton.Enable='on';
            end
        end

        % Value changed function: SnailtrailsCheckBox
        function SnailtrailsCheckBoxValueChanged(app, event)
            app.DSP.snail=app.SnailtrailsCheckBox.Value;
            app.snail=[[nan,nan];app.snail(1:end-1,:)];

            if app.DSP.snail
                set(app.PLOTS.snail,'XData',app.snail(:,2),'YData',app.snail(:,1));
            else
                set(app.PLOTS.snail,'XData',[],'YData',[]);
            end
        end

        % Value changed function: MoplinesCheckBox
        function MoplinesCheckBoxValueChanged(app, event)
            app.DSP.mop=app.MoplinesCheckBox.Value;

            if app.DSP.mop
                set(app.PLOTS.MOP,'XData',app.MOPlon,'YData',app.MOPlat);
            else
                set(app.PLOTS.MOP,'XData',[],'YData',[]);
            end
        end

        % Value changed function: RSSIdotsCheckBox
        function RSSIdotsCheckBoxValueChanged(app, event)
           app.DSP.RSSIdots=app.RSSIdotsCheckBox.Value;

           if app.DSP.RSSIdots
                set(app.PLOTS.RSSIdot,'Xdata',app.RSSIscat(:,2),'Ydata',app.RSSIscat(:,1),'SizeData',app.RSSIscat(:,3));
            else
                set(app.PLOTS.RSSIdot,'XData',[],'YData',[],'SizeData',[]);
            end
        end

        % Value changed function: ZoomRadiusmDropDown
        function ZoomRadiusmDropDownValueChanged(app, event)
            app.mapoffset= meters2GPS(app,app.GPScord(1),str2double(app.ZoomRadiusmDropDown.Value)/2);
            drawnow
        end

        % Button pushed function: NexttagButton
        function NexttagButtonPushed(app, event)
            if app.tagfocus==app.numtags
                app.tagfocus=1;
            else
                app.tagfocus=app.tagfocus+1;
            end

            % Update label
            updatetaglabel(app)

            app.TagSlider.Value=app.tagfocus;
        end

        % Button pushed function: PrevioustagButton
        function PrevioustagButtonPushed(app, event)
            if app.tagfocus==1
                app.tagfocus=app.numtags;
            else
                app.tagfocus=app.tagfocus-1;
            end

            % Update label
            updatetaglabel(app)

            app.TagSlider.Value=app.tagfocus;
        end

        % Value changed function: TagSlider
        function TagSliderValueChanged(app, event)
            app.tagfocus = round(app.TagSlider.Value);

            % Update label
            updatetaglabel(app)
            
        end

        % Button pushed function: LogCobbleButton
        function LogCobbleButtonPushed(app, event)
            checks=false(3,1);

            if strcmp(app.GPSCOMSwitch.Value,'On') %check that GPS com is enabled
                nowutc=[datestr(datetime('now', 'TimeZone','Z'),30),'Z'];
                checks(1)=all(nowutc(1:end-3)==app.GPStime(1:end-6)); %check YYYYMMDDTHHMM matches
                checks(2)=(str2double(nowutc(end-2:end-1))-str2double(app.GPStime(end-5:end-4))) < 3 ; %GPS fix vs datalog button less than 3 seconds apart
                checks(3)=app.tagflags(app.tagfocus)==false; % check that position is not already logged
                %current - consider an RSSI minimum to log a cobble
            elseif app.debug
                checks(:)=1;
            end

            if all(checks)
                cobblelog=[app.GPStime , num2str(app.taglist(app.tagfocus)) , num2str(app.GPScord(1)) , num2str(app.GPScord(2)) , "User saved cobble position"];
                writematrix(cobblelog,app.datalog.filepath,'Delimiter','tab','WriteMode','append');
                
                if app.debug % Warn for developer mode
                    mssg=sprintf('DEVELOPER MODE: Cobble %s Logged',num2str(app.tagfocus));
                    telluser(app,mssg,true);
                    app.tagflags(app.tagfocus)=true;
    
                    errorMessage = sprintf('Currently in DEVELOPER MODE! Skipping all GPS checks.  Make sure your position is accruate when logging cobbles!');
		            uiwait(warndlg(errorMessage));
                else
                    mssg=sprintf('Cobble %s Position Recorded',num2str(app.taglist(app.tagfocus)));
                    telluser(app,mssg,true);
                end
                app.tagflags(app.tagfocus)=true;

                % Update GPS map with cobble flag pos
                app.logpos(app.tagfocus,:)=app.GPScord; %debug!
            else
                telluser(app,'Unable to save location',true)
                errorMessage = sprintf('Unable to save cobble location.  Check that you highlighted the correct tag.  Check GPS COM recieving updates.  Check computer time vs GPS time.');
		        uiwait(warndlg(errorMessage));
            end
        end

        % Value changed function: PausegraphsButton
        function PausegraphsButtonValueChanged(app, event)
            app.DSP.yagigraph = app.PausegraphsButton.Value;
            
        end

        % Button pushed function: REMOVEcobbleButton
        function REMOVEcobbleButtonPushed(app, event)
            if app.tagflags(app.tagfocus)==false % check that position has an entry
                mssg=sprintf('Cobble #%s does not have a saved Position!',num2str(find(app.taglist==app.taglist(app.tagfocus))));
                telluser(app,mssg,true);
            else
                % Poll user to make sure they want to delete the position
                question=sprintf('Are you sure you want to delete position of cobble #%d?',find(app.taglist==app.taglist(app.tagfocus)));
                answer = questdlg(question, ...
	                'WARNING about to delete cobble log!', ...
	                'Yes, delete it','No, keep position','No, keep position');
                
                switch answer
                    case 'Yes, delete it'
                        if isempty(app.GPStime)
                            utctime=[datestr(datetime('now', 'TimeZone','Z'),30),'Z'];
                        else
                            utctime=app.GPStime;
                        end
                        % Print removal message to log
                        cobblelog=[utctime , num2str(app.taglist(app.tagfocus)) , num2str(999) , num2str(999) , "User removed cobble position (999=fill values)"];
                        writematrix(cobblelog,app.datalog.filepath,'Delimiter','tab','WriteMode','append');
                        
                        % UI display cobble removal mssg
                        mssg=sprintf('Cobble %s Position Removed',num2str(find(app.taglist==app.taglist(app.tagfocus))));
                        telluser(app,mssg,true);
                        uiwait(warndlg(mssg));
                    
                        app.tagflags(app.tagfocus)=false; %reset tagfocus
                        app.logpos(app.tagfocus,:)=999; %reset GUI cobble position to transient value
                    case 'No, keep position'
                        % do not do anything if they change their mind
                end
            end
        end

        % Value changed function: GPSBypassSwitch
        function GPSBypassSwitchValueChanged(app, event)
            %{
                A method to run the program without using a GPS.  First
                check the mode that the user requests, then make sure the
                GPS COM port is off before allowing the program to run.
                (then disable the GPS COM options so the user cannot run
                both at once)
            %}
            value = app.GPSBypassSwitch.Value;
            switch value
                case 'On'
                    switch app.GPSCOMSwitch.Value
                    % check that gps switch is not enabled
                        case 'On'
                            % error mssg
                            errormssg=sprintf('GPS timer is already running.  Disable GPS COM before attempting to run the GPS bypass.');
                            uiwait(warndlg(errormssg))
                            app.GPSBypassSwitch.Value='Off';
                        case 'Off'    
                            % disable gps COM functions
                            app.GPSCOMSwitch.Enable=false;
                            app.GPSCOMListBox.Enable=false;
    
                            % Start 1 hz gpstimer
                            app.gpsspoofbool = true;
                            start(app.gpsspooftmr)
        
                            % Turn on UI lamp
                            app.UILamp.Color='g';
                            app.GPSCOMLamp.Enable='on';
                            % Turn on Main Running lamp
                            app.RunningLamp.Color='g';
                            app.RunningLamp.Enable='on';

                            %disable loading Cobble positions while running
                            app.LoadprevPositionsButton.Enable='off';
                    end
                case 'Off'
%                     wait(app.uitmr) %wait until GUI refresh execition
                    stop(app.gpsspooftmr);
                    app.gpsspoofbool = false;
  
%                     app.GPSCOMSwitch.Enable=true;
                    app.GPSCOMListBox.Enable=true;
                            
                    app.UILamp.Color='r';
                    app.GPSCOMLamp.Enable='off';
                    app.RunningLamp.Color='r';
                    app.RunningLamp.Enable='off';

                    %Enable loading Cobble positions while running
                    app.LoadprevPositionsButton.Enable='on';
            end
            
        end

        % Button pushed function: LoadprevPositionsButton
        function LoadprevPositionsButtonPushed(app, event)
            %current -tagflags not enabled when pulling from same date.
            % - app.flagtags2 = imported flags from file only

            % -open uigetfile
            % -run readtable script
            % if date==today
                % -LAT/LON ~= nan become checkflag==1 (turn off cobbles found that day)
            % else
                % -LAT/LON ~= nan becomes orange (previous) marker positions
%             disp('loadpositions button pressed, waiting for UI')
%             wait(app.uitmr) %wait until UI is not refreshing
%             disp('UI finished')

            %Clear previous import values
            app.tagflags(app.tagflags2)=0;
            telluser(app,'cleared tagflags of previous import',0)


            % User selects file to load    
            [filename,path]=uigetfile('*.txt');
            if isequal(filename,0)
                telluser(app,'Canceled loading file',1) % user cancelled action
            else
                app.prevload=readARFIDcobbleLog(fullfile(path,filename));
                app.prevfile=dir(fullfile(path,filename));
                prevdate=datetime(app.prevfile.date,"Format",'uuuuMMdd''T''HHmmss','TimeZone','UTC');
                loadmssg=sprintf('User loaded previous file: %s\nwhich was last modified on: %s\n',app.prevfile.name,prevdate);
                telluser(app,loadmssg,0)
                
                % Check if re-loading a log from the same survey date
                nowutcdt=datetime('now','Format','uuuuMMdd''T''HHmmss','TimeZone','UTC');
                if timecmp(prevdate,nowutcdt,12,"hours")
                    app.tagflags2=~isnan(app.prevload.Lat);
                    app.tagflags2(and(app.tagflags2,app.tagflags))=0; % find any overlap of flags and then ignore them from the imported file
                    app.tagflags(app.tagflags2)=1; % re-flag non-nan values that do not overlap
                else
                    app.tagflags2(:)=0; % clear tagflags2 because import is from another survey
                end

                % map previous positions
                app.prevpos(:,1:2)=[app.prevload.Lat,app.prevload.Lon];
            end

            % Refresh screen to see updates
            wait(app.uitmr) % wait to run until UI is ready for an update
            wait(app.refreshtmr) % wait for other refreshes from user spam
            start(app.refreshtmr) % refresh screen

            telluser(app,'Refreshed UI',true)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.UImssg = uilabel(app.GridLayout7);
            app.UImssg.Visible = 'off';
            app.UImssg.Text = '';
            app.RefreshButton = uibutton(app.GridLayout7, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.LogCobbleButton = uibutton(app.GridLayout7, 'push');
            app.LogCobbleButton.ButtonPushedFcn = createCallbackFcn(app, @LogCobbleButtonPushed, true);
            app.LogCobbleButton.Text = 'Log Cobble';
            app.REMOVEcobbleButton = uibutton(app.GridLayout7, 'push');
            app.REMOVEcobbleButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVEcobbleButtonPushed, true);
            app.REMOVEcobbleButton.Text = 'REMOVE cobble';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ControlsTab = uitab(app.TabGroup);
            app.ControlsTab.Title = 'Controls';
            app.GridLayout2 = uigridlayout(app.ControlsTab);
            app.NexttagButton = uibutton(app.GridLayout2, 'push');
            app.NexttagButton.ButtonPushedFcn = createCallbackFcn(app, @NexttagButtonPushed, true);
            app.NexttagButton.Text = 'Next tag';
            app.PrevioustagButton = uibutton(app.GridLayout2, 'push');
            app.PrevioustagButton.ButtonPushedFcn = createCallbackFcn(app, @PrevioustagButtonPushed, true);
            app.PrevioustagButton.Text = 'Previous tag';
            app.TagIDLabel = uilabel(app.GridLayout2);
            app.TagIDLabel.Text = 'Tag ID: ';
            app.RunningLamp = uilamp(app.GridLayout2);
            app.RunningLabel = uilabel(app.GridLayout2);
            app.RunningLabel.Text = 'Running';
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.AntennaCOMLamp_2 = uilamp(app.GridLayout8);
            app.AntennaLabel = uilabel(app.GridLayout8);
            app.AntennaLabel.Text = 'Antenna';
            app.GPSCOMLamp_2 = uilamp(app.GridLayout8);
            app.GPSCOMLabel_2 = uilabel(app.GridLayout8);
            app.GPSCOMLabel_2.Text = 'GPS';
            app.PausegraphsButton = uibutton(app.GridLayout2, 'state');
            app.PausegraphsButton.ValueChangedFcn = createCallbackFcn(app, @PausegraphsButtonValueChanged, true);
            app.PausegraphsButton.Text = 'Pause graphs';
            app.GridLayout13 = uigridlayout(app.GridLayout2);
            app.TagSliderLabel = uilabel(app.GridLayout13);
            app.TagSliderLabel.Text = 'Tag #';
            app.TagSlider = uislider(app.GridLayout13);
            app.TagSlider.Limits = [1 30];
            app.TagSlider.ValueChangedFcn = createCallbackFcn(app, @TagSliderValueChanged, true);
            app.TagSlider.Value = 1;
            app.ConfigureTab = uitab(app.TabGroup);
            app.ConfigureTab.Title = 'Configure';
            app.GridLayout4 = uigridlayout(app.ConfigureTab);
            app.AntennaCOMLamp = uilamp(app.GridLayout4);
            app.AntennaCOMLamp.Enable = 'off';
            app.AntennaCOMSwitchLabel = uilabel(app.GridLayout4);
            app.AntennaCOMSwitchLabel.Text = 'Antenna COM';
            app.AntennaCOMSwitch = uiswitch(app.GridLayout4, 'slider');
            app.AntennaCOMSwitch.ValueChangedFcn = createCallbackFcn(app, @AntennaCOMSwitchValueChanged, true);
            app.AntennaCOMSwitch.Enable = 'off';
            app.AntennaCOMListBox = uilistbox(app.GridLayout4);
            app.AntennaCOMListBox.Items = {'list COM ports'};
            app.AntennaCOMListBox.ValueChangedFcn = createCallbackFcn(app, @AntennaCOMListBoxValueChanged, true);
            app.AntennaCOMListBox.Value = 'list COM ports';
            app.GPSCOMLamp = uilamp(app.GridLayout4);
            app.GPSCOMLamp.Enable = 'off';
            app.GPSCOMSwitchLabel = uilabel(app.GridLayout4);
            app.GPSCOMSwitchLabel.Text = 'GPS COM';
            app.GPSCOMSwitch = uiswitch(app.GridLayout4, 'slider');
            app.GPSCOMSwitch.ValueChangedFcn = createCallbackFcn(app, @GPSCOMSwitchValueChanged, true);
            app.GPSCOMSwitch.Enable = 'off';
            app.GPSCOMListBox = uilistbox(app.GridLayout4);
            app.GPSCOMListBox.Items = {'list COM ports'};
            app.GPSCOMListBox.ValueChangedFcn = createCallbackFcn(app, @GPSCOMListBoxValueChanged, true);
            app.GPSCOMListBox.Value = 'list COM ports';
            app.UILamp = uilamp(app.GridLayout4);
            app.UISwitchLabel = uilabel(app.GridLayout4);
            app.UISwitchLabel.Text = 'UI update';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.MoplinesCheckBox = uicheckbox(app.GridLayout5);
            app.MoplinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MoplinesCheckBoxValueChanged, true);
            app.MoplinesCheckBox.Text = 'Mop lines';
            app.MoplinesCheckBox.Value = true;
            app.SnailtrailsCheckBox = uicheckbox(app.GridLayout5);
            app.SnailtrailsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SnailtrailsCheckBoxValueChanged, true);
            app.SnailtrailsCheckBox.Text = 'Snail trails';
            app.SnailtrailsCheckBox.Value = true;
            app.ZoomRadiusmDropDown = uidropdown(app.GridLayout5);
            app.ZoomRadiusmDropDown.Items = {'50', '100', '200', '500', '750', '1000'};
            app.ZoomRadiusmDropDown.ValueChangedFcn = createCallbackFcn(app, @ZoomRadiusmDropDownValueChanged, true);
            app.ZoomRadiusmDropDown.Value = '200';
            app.ZoomRadiusmDropDownLabel = uilabel(app.GridLayout5);
            app.ZoomRadiusmDropDownLabel.Text = 'Zoom Radius (m)';
            app.RSSIdotsCheckBox = uicheckbox(app.GridLayout5);
            app.RSSIdotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RSSIdotsCheckBoxValueChanged, true);
            app.RSSIdotsCheckBox.Text = 'RSSI dots';
            app.RSSIdotsCheckBox.Value = true;
            app.LoadprevPositionsButton = uibutton(app.GridLayout5, 'push');
            app.LoadprevPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadprevPositionsButtonPushed, true);
            app.LoadprevPositionsButton.Text = 'Load prev. Positions';
            app.GPSBypassSwitch = uiswitch(app.GridLayout4, 'slider');
            app.GPSBypassSwitch.ValueChangedFcn = createCallbackFcn(app, @GPSBypassSwitchValueChanged, true);
            app.GPSBypassLabel = uilabel(app.GridLayout4);
            app.GPSBypassLabel.Text = 'GPS Bypass (1 Hz)';
            app.ConsoleTab = uitab(app.TabGroup);
            app.ConsoleTab.Title = 'Console';
            app.GridLayout3 = uigridlayout(app.ConsoleTab);
            app.ConsoleOutputTextAreaLabel = uilabel(app.GridLayout3);
            app.ConsoleOutputTextAreaLabel.Text = 'Console Output';
            app.ConsoleOutputTextArea = uitextarea(app.GridLayout3);
            app.ConsoleOutputTextArea.Value = {'Cobblefinder Startup'};
            app.GridLayout10 = uigridlayout(app.GridLayout);
            app.GPSAxes = uiaxes(app.GridLayout10);
            title(app.GPSAxes, 'GPS')
            xlabel(app.GPSAxes, 'Longitude')
            ylabel(app.GPSAxes, 'Latitude')
            zlabel(app.GPSAxes, 'Z')
            app.SpectrumAxes = uiaxes(app.GridLayout10);
            title(app.SpectrumAxes, 'Spectrum Graph')
            xlabel(app.SpectrumAxes, 'time (s)')
            ylabel(app.SpectrumAxes, 'Tag ID #')
            zlabel(app.SpectrumAxes, 'Z')
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.YagiPlotTab = uitab(app.TabGroup2);
            app.YagiPlotTab.Title = 'Yagi Plot';
            app.GridLayout12 = uigridlayout(app.YagiPlotTab);
            app.YagiAxes = uiaxes(app.GridLayout12);
            xlabel(app.YagiAxes, 'time (s)')
            ylabel(app.YagiAxes, 'RSSI')
            zlabel(app.YagiAxes, 'Z')
            app.HistogramTab = uitab(app.TabGroup2);
            app.HistogramTab.Title = 'Histogram';
            app.GridLayout11 = uigridlayout(app.HistogramTab);
            app.RSSIAxes = uiaxes(app.GridLayout11);
            title(app.RSSIAxes, 'RSSI Histogram')
            xlabel(app.RSSIAxes, 'Tag ID')
            ylabel(app.RSSIAxes, 'Signal Strength')
            zlabel(app.RSSIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cobblefinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end